classdef SimExp < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure              matlab.ui.Figure
    GridLayout            matlab.ui.container.GridLayout
    LeftPanel             matlab.ui.container.Panel
    PlantLabel            matlab.ui.control.Label
    ControllerLabel       matlab.ui.control.Label
    EstimatorLabel        matlab.ui.control.Label
    ReferenceLabel        matlab.ui.control.Label
    SensorLabel           matlab.ui.control.Label
    ModelLabel            matlab.ui.control.Label
    RunmodeListBox        matlab.ui.control.ListBox
    RunmodeListBoxLabel   matlab.ui.control.Label
    ModeDropDown          matlab.ui.control.DropDown
    ModeDropDownLabel     matlab.ui.control.Label
    CenterPanel           matlab.ui.container.Panel
    CurrenttimeinfoLabel  matlab.ui.control.Label
    StartButton           matlab.ui.control.Button
    Label                 matlab.ui.control.Label
    TimeSlider            matlab.ui.control.Slider
    TimeSliderLabel       matlab.ui.control.Label
    Lamp                  matlab.ui.control.Lamp
    LampLabel             matlab.ui.control.Label
    RightPanel            matlab.ui.container.Panel
    DescriptionLabel      matlab.ui.control.Label
    UIAxes3               matlab.ui.control.UIAxes
    UIAxes2               matlab.ui.control.UIAxes
    UIAxes                matlab.ui.control.UIAxes
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
    twoPanelWidth = 768;
  end


    properties (Access = private)
        fStart = 0;
        fInit  = 0;
    end
    properties (Access = public)
        fDebug
        debug_func
        fExp = 0;
        mode = [];
        logger
        env = [];
        time
        cha = "";
        cha0 = "";
        agent
        motive = [];
    end

    methods (Access = private)
        function arming(app)
        end
        function takeoff(app)
        end
        function flight(app)
        end
        function landing(app)
        end
        function stopProp(app)
        end
        function Quit(app)
        end
        function do_simulation(app)
            app.agent.do_sensor(app.time,app.cha,app.logger,app.env);
            app.agent.do_estimator(app.time,app.cha,app.logger,app.env);
            app.agent.do_reference(app.time,app.cha,app.logger,app.env);
            app.agent.do_controller(app.time,app.cha,app.logger,app.env);
            app.agent.do_model(app.time,app.cha,app.logger,app.env);
            app.agent.do_plant(app.time,app.cha,app.logger,app.env);
            app.logger.logging(app.time, app.cha, app.agent,[]);
        end

        function loop(app)
            pause(0.1);
            if app.fExp % experiment
                app.Label.Text = ["======= EXPERIMENT ========","Start! Press key code" + app.cha,"s : stop", "a : arming", "t : take off", "f : fligh", "l : landing"];
                uiwait(app.UIFigure)
                while app.fStart
                    tStart = tic;
                    pause(0.001);
                    app.CurrenttimeinfoLabel.Text = ["time : " + app.time.t];
                    if app.cha0 ~= app.cha
                        switch app.cha
                            case {'q',' '}
                                app.Label.Text = "Quit the trial";
                                break;
                            case 's'
                                app.Label.Text = "Stop propellas";
                                app.Lamp.Color = [0 1 0];
                            case 'f'
                                app.Label.Text = "Flight";
                            case 'l'
                                app.Label.Text = "Landing";
                            case 't'
                                app.Label.Text = "Take-off";
                            case 'a'
                                app.Label.Text = "Arming";
                                app.Lamp.Color = [1 0 0];
                        end
                        app.cha0 = app.cha;
                    end
                    if ~isempty(app.motive);              app.motive.getData([]);            end
                    app.do_simulation();
                    app.time.t = app.time.t + toc(tStart);
                end
                app.stop;
            else % simulation
                app.Label.Text = ["Start! Press any key to start except for 'q'", "q : Quit the trial"];
                uiwait(app.UIFigure)
                while app.time.t < app.time.te
                    pause(0.001);
                    app.CurrenttimeinfoLabel.Text = ["time : " + app.time.t];
                    if app.cha0 ~= app.cha
                        switch app.cha
                            case {'q',' '}
                                app.Label.Text = "Quit the trial";
                                app.Lamp.Color = [1 0 0];
                                break;
                            otherwise
                        end
                        app.cha0 = app.cha;
                    end
                    if ~isempty(app.motive)
                        app.motive.getData(app.agent);
                    end
                    app.do_simulation();
                    app.time.t = app.time.t + app.time.dt ;
                    if app.fDebug && app.time.t > app.time.dt*2
                                  app.UIFigure.AutoResizeChildren='off';
                        app.debug_func(app);
                    end
                end
                app.stop;
            end
        end

        function keyPressFunc(app,src,event)
            app.cha = event.Character;
            if strcmp(app.cha,'p')
                uiresume(src);
            end
        end
        function stop(app)
            app.fStart = 0;
            app.StartButton.Text = "Start";
            app.Lamp.Color = [0 0 0];
            app.Label.Text = "Program Stop";
            app.cha = "";
            app.cha0 = "";
        end
    end

  % Callbacks that handle component events
  methods (Access = private)

    % Value changed function: RunmodeListBox
    function switch_run_mode(app, event)
            value = app.RunmodeListBox.Value;
            if strcmp(value,"Experiment")
                app.fExp = 1;
            else
                app.fExp = 0;
            end
    end

    % Drop down opening function: ModeDropDown
    function read_modes(app, event)
            app.Lamp.Color = [0 0 0];
            if app.fExp
                app.ModeDropDown.Items = evalin('base','ExpBaseMode');
            else
                app.ModeDropDown.Items = evalin('base','SimBaseMode');
            end
            app.stop
    end

    % Value changed function: ModeDropDown
    function set_mode(app, event)
            app.mode = app.ModeDropDown.Value;
            run(app.mode);
            app.agent = agent;
            app.PlantLabel.Text = ["Plant : "+ agent.plant.name];
            app.ModelLabel.Text = ["Model : "+ agent.model.name];
            app.SensorLabel.Text = ["Sensor : "+ agent.sensor.name];
            app.ReferenceLabel.Text = ["Reference : "+ agent.reference.name];
            app.ControllerLabel.Text = ["Controller : "+ agent.controller.name];
            app.logger = logger;
            app.time = time;
            app.fDebug = fDebug;
            if fDebug
                app.debug_func = debug_func;
            end
            app.TimeSlider.Value = 0;
            app.TimeSlider.Limits = [time.ts time.te];
            if exist("motive","var"); app.motive = motive; end
    end

    % Button pushed function: StartButton
    function start(app, event)
            app.Label.VerticalAlignment = 'top';
            app.UIFigure.WindowKeyPressFcn = @app.keyPressFunc;
            if ~isempty(app.mode)
                if app.fStart % to stop
                    app.stop;
                else % to start
                    app.fStart = 1;
                    app.StartButton.Text = "Stop";
                    app.Lamp.Color = [0 1 0];
                    app.Label.Text = "Press Enter key to start.";
                    app.loop();
                end
            else
                app.Label.Text = "Set mode first";
                app.stop;
            end
    end

    % Value changing function: TimeSlider
    function set_current_time(app, event)
      app.time.t = event.Value;
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
      currentFigureWidth = app.UIFigure.Position(3);
      if(currentFigureWidth <= app.onePanelWidth)
          % Change to a 3x1 grid
          app.GridLayout.RowHeight = {644, 644, 644};
          app.GridLayout.ColumnWidth = {'1x'};
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = 1;
          app.LeftPanel.Layout.Row = 2;
          app.LeftPanel.Layout.Column = 1;
          app.RightPanel.Layout.Row = 3;
          app.RightPanel.Layout.Column = 1;
      elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
          % Change to a 2x2 grid
          app.GridLayout.RowHeight = {644, 644};
          app.GridLayout.ColumnWidth = {'1x', '1x'};
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = [1,2];
          app.LeftPanel.Layout.Row = 2;
          app.LeftPanel.Layout.Column = 1;
          app.RightPanel.Layout.Row = 2;
          app.RightPanel.Layout.Column = 2;
      else
          % Change to a 1x3 grid
          app.GridLayout.RowHeight = {'1x'};
          app.GridLayout.ColumnWidth = {203, '1x', 411};
          app.LeftPanel.Layout.Row = 1;
          app.LeftPanel.Layout.Column = 1;
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = 2;
          app.RightPanel.Layout.Row = 1;
          app.RightPanel.Layout.Column = 3;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.ModeDropDownLabel = uilabel(app.LeftPanel);
      app.ModeDropDownLabel.Text = 'Mode';
      app.ModeDropDown = uidropdown(app.LeftPanel);
      app.ModeDropDown.Items = {'Default'};
      app.ModeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @read_modes, true);
      app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @set_mode, true);
      app.ModeDropDown.Value = 'Default';
      app.RunmodeListBoxLabel = uilabel(app.LeftPanel);
      app.RunmodeListBoxLabel.Text = 'Run mode';
      app.RunmodeListBox = uilistbox(app.LeftPanel);
      app.RunmodeListBox.Items = {'Experiment', 'Simulation'};
      app.RunmodeListBox.ValueChangedFcn = createCallbackFcn(app, @switch_run_mode, true);
      app.RunmodeListBox.Value = 'Simulation';
      app.ModelLabel = uilabel(app.LeftPanel);
      app.ModelLabel.Text = 'Model';
      app.SensorLabel = uilabel(app.LeftPanel);
      app.SensorLabel.Text = 'Sensor';
      app.ReferenceLabel = uilabel(app.LeftPanel);
      app.ReferenceLabel.Text = 'Reference';
      app.EstimatorLabel = uilabel(app.LeftPanel);
      app.EstimatorLabel.Text = 'Estimator';
      app.ControllerLabel = uilabel(app.LeftPanel);
      app.ControllerLabel.Text = 'Controller';
      app.PlantLabel = uilabel(app.LeftPanel);
      app.PlantLabel.Text = 'Plant';
      app.CenterPanel = uipanel(app.GridLayout);
      app.LampLabel = uilabel(app.CenterPanel);
      app.LampLabel.Text = 'Lamp';
      app.Lamp = uilamp(app.CenterPanel);
      app.TimeSliderLabel = uilabel(app.CenterPanel);
      app.TimeSliderLabel.Text = 'Time';
      app.TimeSlider = uislider(app.CenterPanel);
      app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @set_current_time, true);
      app.Label = uilabel(app.CenterPanel);
      app.Label.Text = '';
      app.StartButton = uibutton(app.CenterPanel, 'push');
      app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
      app.StartButton.Text = 'Start';
      app.CurrenttimeinfoLabel = uilabel(app.CenterPanel);
      app.CurrenttimeinfoLabel.Text = 'Current time info';
      app.RightPanel = uipanel(app.GridLayout);
      app.UIAxes = uiaxes(app.RightPanel);
      title(app.UIAxes, 'Title')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      zlabel(app.UIAxes, 'Z')
      app.UIAxes2 = uiaxes(app.RightPanel);
      title(app.UIAxes2, 'Title')
      xlabel(app.UIAxes2, 'X')
      ylabel(app.UIAxes2, 'Y')
      zlabel(app.UIAxes2, 'Z')
      app.UIAxes3 = uiaxes(app.RightPanel);
      title(app.UIAxes3, 'Title')
      xlabel(app.UIAxes3, 'X')
      ylabel(app.UIAxes3, 'Y')
      zlabel(app.UIAxes3, 'Z')
      app.DescriptionLabel = uilabel(app.RightPanel);
      app.DescriptionLabel.Text = 'Description';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SimExp

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end